classdef MatGrabber < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        UserInputPanel               matlab.ui.container.Panel
        SynchronousInformationPanel  matlab.ui.container.Panel
        FilterMagnitudeSpinner       matlab.ui.control.Spinner
        HeightSpinner                matlab.ui.control.Spinner
        WidthSpinner                 matlab.ui.control.Spinner
        RefreshRateSpinner           matlab.ui.control.Spinner
        RefreshRateLabel             matlab.ui.control.Label
        WidthLabel                   matlab.ui.control.Label
        HeightLabel                  matlab.ui.control.Label
        FilterMagnitudeLabel         matlab.ui.control.Label
        ModeSelectDropDown           matlab.ui.control.DropDown
        FileModePanel                matlab.ui.container.Panel
        SearchFileButton             matlab.ui.control.Button
        FilePathField                matlab.ui.control.EditField
        BrowseFileMessage            matlab.ui.control.Label
        FileModeStartButton          matlab.ui.control.Button
        SDRModePanel                 matlab.ui.container.Panel
        SampleRateMHzField           matlab.ui.control.NumericEditField
        FrequencyMHzField            matlab.ui.control.NumericEditField
        FrequencyMHzEditFieldLabel   matlab.ui.control.Label
        BandwidthField               matlab.ui.control.NumericEditField
        BandwidthLabel               matlab.ui.control.Label
        ConnectionStatusLabel        matlab.ui.control.Label
        SampleRateMHzEditFieldLabel  matlab.ui.control.Label
        ConnectSDRMessage            matlab.ui.control.Label
        SDRConnectButton             matlab.ui.control.Button
        SDRModeStartButton           matlab.ui.control.StateButton
        ResultDisplayPanel           matlab.ui.container.Panel
        RightButton                  matlab.ui.control.Button
        LeftButton                   matlab.ui.control.Button
        DownButton                   matlab.ui.control.Button
        UpButton                     matlab.ui.control.Button
        ResultTitle                  matlab.ui.control.Label
        ReconstructedImage           matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        file_path % Description
        resulting_image % Description
        h_offset = 0; % Description
        v_offset = 0; % Description
        SDR_device; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clear all;
            close;
            clc;
        end

        % Value changed function: ModeSelectDropDown
        function ModeSelectDropDownValueChanged(app, event)
            value = app.ModeSelectDropDown.Value;
            if strcmpi(value, 'File Mode')
                set(app.FileModePanel, 'Visible', 'on');
                set(app.FileModePanel, 'Enable', 'on');
                set(app.SDRModePanel, 'Visible', 'off');
                set(app.SDRModePanel, 'Enable', 'off');

                set(app.RefreshRateSpinner, 'Value', 0);
                set(app.HeightSpinner, 'Value', 0);
                set(app.WidthSpinner, 'Value', 0);
                set(app.FilterMagnitudeSpinner, 'Value', 0);
                delete(app.resulting_image);

                if strcmp(app.SDRConnectButton.Text, 'Disconnect')
                    app.SDR_device.Close;
                    set(app.SDRConnectButton, 'Text', 'Connect');
                    set(app.ConnectionStatusLabel, 'Text', 'Not yet connected...');
                    set(app.SDRModeStartButton, 'Enable', 'off');
                end
            else
                set(app.FileModePanel, 'Visible', 'off');
                set(app.FileModePanel, 'Enable', 'off');
                set(app.SDRModePanel, 'Visible', 'on');
                set(app.SDRModePanel, 'Enable', 'on');
                set(app.FilePathField, 'Value', '');

                clear app.file_path;
                set(app.FileModeStartButton, 'Enable', 'off');
                set(app.RefreshRateSpinner, 'Value', 0);
                set(app.HeightSpinner, 'Value', 0);
                set(app.WidthSpinner, 'Value', 0);
                set(app.FilterMagnitudeSpinner, 'Value', 0);
                delete(app.resulting_image);
            end
        end

        % Button pushed function: SearchFileButton
        function SearchFileButtonPushed(app, event)
            [file, path] = uigetfile('*.wav');
            figure(app.UIFigure);
            app.file_path = fullfile(path, file);
            if not(isequal(file, 0))
                set(app.FilePathField, 'Value', file);
                set(app.FileModeStartButton, 'Enable', 'on');
            end
        end

        % Button pushed function: FileModeStartButton
        function FileModeStartButtonPushed(app, event)
            if isempty(app.FilePathField.Value)
                uialert(app.UIFigure,'Please select a file.', 'Error');
            else
                set(app.SearchFileButton, 'Enable', 'off');
                set(app.FilePathField, 'Enable', 'off');
                set(app.BrowseFileMessage, 'Enable', 'off');
                set(app.FileModeStartButton, 'Enable', 'off');
                set(app.ModeSelectDropDown, 'Enable', 'off');

                set(app.RefreshRateSpinner, 'Value', 0);
                set(app.HeightSpinner, 'Value', 0);
                set(app.WidthSpinner, 'Value', 0);
                set(app.FilterMagnitudeSpinner, 'Value', 0);

                % Initialise .m files
                videoModes = Modes;
                demodulator = AMDemodulation();
                resample = Resampling();
                sync = SyncDetection();
                reshaped = Reshape();

                % Start program
                input = InputSignalViaFile(app.file_path);
                input_signal = input.getSignal;
                sample_rate = input.getSampleRate;
                frame = FrameDetection(sample_rate, input_signal, videoModes.getVideoModes);

                % Update synchronous information to GUI
                set(app.RefreshRateSpinner, 'Value', frame.getRefreshRate);
                set(app.HeightSpinner, 'Value', frame.getFrameHeight);
                set(app.WidthSpinner, 'Value', frame.getFrameWidth);
                set(app.FilterMagnitudeSpinner, 'Value', 1.0);

                % Enable user adjustment
                set(app.SynchronousInformationPanel, 'Enable', 'on');
                set(app.RefreshRateSpinner, 'Enable', 'on');
                set(app.RefreshRateSpinner, 'ValueDisplayFormat', '%.8f')
                set(app.HeightSpinner, 'Enable', 'on');
                set(app.WidthSpinner, 'Enable', 'on');
                set(app.FilterMagnitudeSpinner, 'Enable', 'on');
                set(app.UpButton, 'Enable', 'on');
                set(app.DownButton, 'Enable', 'on');
                set(app.LeftButton, 'Enable', 'on');
                set(app.RightButton, 'Enable', 'on');

                % Prepare screen recovery
                wav_file = dsp.AudioFileReader(app.file_path);
                num_samples = round(frame.getFrameHeight*frame.getFrameWidth*round(frame.getPixelRate/sample_rate));
                wav_file.SamplesPerFrame = num_samples;
                starting_sample = 1;
                total_samples = input.getSampleNum;

                % Start screen recovery
                while (starting_sample < total_samples-starting_sample)
                    in = wav_file();
                    demod_signal = process(demodulator, in, 'File');
    
                    % Get values from user
                    refresh_rate = get(app.RefreshRateSpinner, 'Value');
                    frame_height = get(app.HeightSpinner, 'Value');
                    frame_width = get(app.WidthSpinner, 'Value');
                    pixels_per_frame = frame_height*frame_width;
                    pixel_rate = round(frame_width*frame_height*refresh_rate);
                    filter_magnitude = get(app.FilterMagnitudeSpinner, 'Value');
                        
                    % Continue screen recovery
                    resampled_signal = process(resample, demod_signal(1:num_samples-1), pixel_rate, sample_rate);
                    reshaped_signal = process(reshaped, resampled_signal, pixels_per_frame, frame_height, frame_width, filter_magnitude);
                    synced_signal = process(sync, reshaped_signal, app.h_offset, app.v_offset);
                    app.resulting_image = image(synced_signal, 'CDataMapping','scaled', 'Parent', app.ReconstructedImage); 
                    drawnow
    
                    starting_sample = starting_sample+num_samples;
                end

                % Reset GUI
                set(app.SearchFileButton, 'Enable', 'on');
                set(app.BrowseFileMessage, 'Enable', 'on');
                set(app.FileModeStartButton, 'Enable', 'on');
                set(app.ModeSelectDropDown, 'Enable', 'on');

                set(app.RefreshRateSpinner, 'Enable', 'off');
                set(app.HeightSpinner, 'Enable', 'off');
                set(app.WidthSpinner, 'Enable', 'off');
                set(app.FilterMagnitudeSpinner, 'Enable', 'off');
                set(app.UpButton, 'Enable', 'off');
                set(app.DownButton, 'Enable', 'off');
                set(app.LeftButton, 'Enable', 'off');
                set(app.RightButton, 'Enable', 'off');

                app.h_offset = 0;
                app.v_offset = 0;
            end
        end

        % Button pushed function: LeftButton
        function LeftButtonPushed(app, event)
            app.h_offset = app.h_offset - 10;
        end

        % Button pushed function: RightButton
        function RightButtonPushed(app, event)
            app.h_offset = app.h_offset + 10;
        end

        % Button pushed function: UpButton
        function UpButtonPushed(app, event)
            app.v_offset = app.v_offset - 10;
        end

        % Button pushed function: DownButton
        function DownButtonPushed(app, event)
            app.v_offset = app.v_offset + 10;
        end

        % Button pushed function: SDRConnectButton
        function SDRConnectButtonPushed(app, event)
            if strcmp(app.SDRConnectButton.Text, 'Connect')
                try
                    app.SDR_device = InputSignalViaRSP;
                    set(app.ConnectionStatusLabel, 'Text', 'Connect successfully');
                    set(app.SDRConnectButton, 'Text', 'Disconnect');
                    set(app.SDRModeStartButton, 'Enable', 'on');
                catch
                    app.SDR_device.Close;
                    set(app.ConnectionStatusLabel, 'Text', 'Error! Please connect again.');
                end
            else
                app.SDR_device.Close;
                set(app.SDRConnectButton, 'Text', 'Connect');
                set(app.ConnectionStatusLabel, 'Text', 'Not yet connected...');
                set(app.SDRModeStartButton, 'Enable', 'off');
            end
        end

        % Value changed function: SDRModeStartButton
        function SDRModeStartButtonValueChanged(app, event)
            value = app.SDRModeStartButton.Value;
            
            if value == 1
                set(app.SDRModeStartButton, 'Text', 'Stop');
                set(app.SDRConnectButton, 'Enable', 'off');
                set(app.SampleRateMHzField, 'Enable', 'off');
                set(app.FrequencyMHzField, 'Enable', 'off');
                set(app.BandwidthField, 'Enable', 'off');

                device = app.SDR_device;
                device.SampleRateMHz = app.SampleRateMHzField.Value;
                device.FrequencyMHz = app.FrequencyMHzField.Value;
                device.Bandwidth = app.BandwidthField.Value;

                device.Stream;
                sample_rate = device.SampleRateMHz * 1e6;

                % Initialise .m files
                videoModes = Modes;
                demodulator = AMDemodulation();
                resample = Resampling();
                sync = SyncDetection();
                reshaped = Reshape();

                Time2Fill_Buffer = 2e6/sample_rate;
                pause(Time2Fill_Buffer);
                in = RSP_MEX('data');
                frame = FrameDetection(sample_rate, in, videoModes.getVideoModes);

                % Update synchronous information to GUI
                set(app.RefreshRateSpinner, 'Value', frame.getRefreshRate);
                set(app.HeightSpinner, 'Value', frame.getFrameHeight);
                set(app.WidthSpinner, 'Value', frame.getFrameWidth);
                set(app.FilterMagnitudeSpinner, 'Value', 1.0);

                % Enable user adjustment
                set(app.SynchronousInformationPanel, 'Enable', 'on');
                set(app.RefreshRateSpinner, 'Enable', 'on');
                set(app.RefreshRateSpinner, 'ValueDisplayFormat', '%.8f')
                set(app.HeightSpinner, 'Enable', 'on');
                set(app.WidthSpinner, 'Enable', 'on');
                set(app.FilterMagnitudeSpinner, 'Enable', 'on');
                set(app.UpButton, 'Enable', 'on');
                set(app.DownButton, 'Enable', 'on');
                set(app.LeftButton, 'Enable', 'on');
                set(app.RightButton, 'Enable', 'on');

                while 1
                    in = RSP_MEX('data');

                    demod_signal = process(demodulator, in, 'SDR');

                    % Get values from user
                    refresh_rate = get(app.RefreshRateSpinner, 'Value');
                    frame_height = get(app.HeightSpinner, 'Value');
                    frame_width = get(app.WidthSpinner, 'Value');
                    filter_magnitude = get(app.FilterMagnitudeSpinner, 'Value');

                    pixels_per_frame = frame_height*frame_width;
                    pixel_rate = round(frame_width*frame_height*refresh_rate);
                        
                    % Continue screen recovery
                    resampled_signal = process(resample, demod_signal, pixel_rate, sample_rate);
                    reshaped_signal = process(reshaped, resampled_signal, pixels_per_frame, frame_height, frame_width, filter_magnitude);
                    synced_signal = process(sync, reshaped_signal, app.h_offset, app.v_offset);
                    app.resulting_image = image(synced_signal, 'CDataMapping','scaled', 'Parent', app.ReconstructedImage); 
                    drawnow

                    pause(Time2Fill_Buffer)
                end
            else
                app.SDR_device.StopStream;
                app.SDR_device.Close;

                % Reset GUI
                set(app.SDRModeStartButton, 'Text', 'Start');
                set(app.SDRConnectButton, 'Enable', 'on');
                set(app.SampleRateMHzField, 'Enable', 'on');
                set(app.FrequencyMHzField, 'Enable', 'on');
                set(app.BandwidthField, 'Enable', 'on');

                set(app.SearchFileButton, 'Enable', 'on');
                set(app.BrowseFileMessage, 'Enable', 'on');
                set(app.FileModeStartButton, 'Enable', 'on');
                set(app.ModeSelectDropDown, 'Enable', 'on');

                set(app.RefreshRateSpinner, 'Enable', 'off');
                set(app.HeightSpinner, 'Enable', 'off');
                set(app.WidthSpinner, 'Enable', 'off');
                set(app.FilterMagnitudeSpinner, 'Enable', 'off');
                set(app.UpButton, 'Enable', 'off');
                set(app.DownButton, 'Enable', 'off');
                set(app.LeftButton, 'Enable', 'off');
                set(app.RightButton, 'Enable', 'off');

                app.h_offset = 0;
                app.v_offset = 0;
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            confirm = uiconfirm(app.UIFigure, 'Do you want to close the app?', 'Close request');
            if strcmp(confirm, 'OK')
                delete(app)
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {423, 423};
                app.GridLayout.ColumnWidth = {'1x'};
                app.ResultDisplayPanel.Layout.Row = 2;
                app.ResultDisplayPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {232, '1x'};
                app.ResultDisplayPanel.Layout.Row = 1;
                app.ResultDisplayPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UserInputPanel = uipanel(app.GridLayout);
            app.SDRModePanel = uipanel(app.UserInputPanel);
            app.SDRModePanel.Enable = 'off';
            app.SDRModePanel.Visible = 'off';
            app.SDRModeStartButton = uibutton(app.SDRModePanel, 'state');
            app.SDRModeStartButton.ValueChangedFcn = createCallbackFcn(app, @SDRModeStartButtonValueChanged, true);
            app.SDRModeStartButton.Enable = 'off';
            app.SDRModeStartButton.Text = 'Start';
            app.SDRConnectButton = uibutton(app.SDRModePanel, 'push');
            app.SDRConnectButton.ButtonPushedFcn = createCallbackFcn(app, @SDRConnectButtonPushed, true);
            app.SDRConnectButton.Text = 'Connect';
            app.ConnectSDRMessage = uilabel(app.SDRModePanel);
            app.ConnectSDRMessage.Text = 'Connect SDR device:';
            app.SampleRateMHzEditFieldLabel = uilabel(app.SDRModePanel);
            app.SampleRateMHzEditFieldLabel.Text = 'Sample Rate (MHz)';
            app.ConnectionStatusLabel = uilabel(app.SDRModePanel);
            app.ConnectionStatusLabel.Text = 'Not yet connected...';
            app.BandwidthLabel = uilabel(app.SDRModePanel);
            app.BandwidthLabel.Text = 'Bandwidth (Hz)';
            app.BandwidthField = uieditfield(app.SDRModePanel, 'numeric');
            app.BandwidthField.Limits = [0 Inf];
            app.FrequencyMHzEditFieldLabel = uilabel(app.SDRModePanel);
            app.FrequencyMHzEditFieldLabel.Text = 'Frequency (MHz)';
            app.FrequencyMHzField = uieditfield(app.SDRModePanel, 'numeric');
            app.FrequencyMHzField.Limits = [0 Inf];
            app.SampleRateMHzField = uieditfield(app.SDRModePanel, 'numeric');
            app.SampleRateMHzField.Limits = [0 Inf];
            app.FileModePanel = uipanel(app.UserInputPanel);
            app.FileModeStartButton = uibutton(app.FileModePanel, 'push');
            app.FileModeStartButton.ButtonPushedFcn = createCallbackFcn(app, @FileModeStartButtonPushed, true);
            app.FileModeStartButton.Enable = 'off';
            app.FileModeStartButton.Text = 'Start';
            app.BrowseFileMessage = uilabel(app.FileModePanel);
            app.BrowseFileMessage.Text = 'Select pre-recorded file:';
            app.FilePathField = uieditfield(app.FileModePanel, 'text');
            app.FilePathField.Editable = 'off';
            app.FilePathField.Enable = 'off';
            app.FilePathField.Tooltip = {''};
            app.SearchFileButton = uibutton(app.FileModePanel, 'push');
            app.SearchFileButton.ButtonPushedFcn = createCallbackFcn(app, @SearchFileButtonPushed, true);
            app.SearchFileButton.Tooltip = {''};
            app.SearchFileButton.Text = 'Browse';
            app.ModeSelectDropDown = uidropdown(app.UserInputPanel);
            app.ModeSelectDropDown.Items = {'File Mode', 'SDR Mode'};
            app.ModeSelectDropDown.ValueChangedFcn = createCallbackFcn(app, @ModeSelectDropDownValueChanged, true);
            app.ModeSelectDropDown.Value = 'File Mode';
            app.SynchronousInformationPanel = uipanel(app.UserInputPanel);
            app.SynchronousInformationPanel.Title = 'Synchronous Information';
            app.FilterMagnitudeLabel = uilabel(app.SynchronousInformationPanel);
            app.FilterMagnitudeLabel.Text = 'Filter Magnitude';
            app.HeightLabel = uilabel(app.SynchronousInformationPanel);
            app.HeightLabel.Text = 'Height';
            app.WidthLabel = uilabel(app.SynchronousInformationPanel);
            app.WidthLabel.Text = 'Width';
            app.RefreshRateLabel = uilabel(app.SynchronousInformationPanel);
            app.RefreshRateLabel.Text = 'Refresh Rate';
            app.RefreshRateSpinner = uispinner(app.SynchronousInformationPanel);
            app.RefreshRateSpinner.Step = 1e-08;
            app.RefreshRateSpinner.Limits = [0 Inf];
            app.RefreshRateSpinner.Enable = 'off';
            app.WidthSpinner = uispinner(app.SynchronousInformationPanel);
            app.WidthSpinner.Limits = [0 Inf];
            app.WidthSpinner.Enable = 'off';
            app.HeightSpinner = uispinner(app.SynchronousInformationPanel);
            app.HeightSpinner.Limits = [0 Inf];
            app.HeightSpinner.Enable = 'off';
            app.FilterMagnitudeSpinner = uispinner(app.SynchronousInformationPanel);
            app.FilterMagnitudeSpinner.Step = 0.1;
            app.FilterMagnitudeSpinner.Limits = [0 Inf];
            app.FilterMagnitudeSpinner.Enable = 'off';
            app.ResultDisplayPanel = uipanel(app.GridLayout);
            app.ReconstructedImage = uiaxes(app.ResultDisplayPanel);
            colormap(app.ReconstructedImage, 'gray')
            app.ResultTitle = uilabel(app.ResultDisplayPanel);
            app.ResultTitle.Text = 'Reconstructed Image';
            app.UpButton = uibutton(app.ResultDisplayPanel, 'push');
            app.UpButton.ButtonPushedFcn = createCallbackFcn(app, @UpButtonPushed, true);
            app.UpButton.Icon = fullfile(pathToMLAPP, 'Icon', 'up_icon.png');
            app.UpButton.Enable = 'off';
            app.UpButton.Text = '';
            app.DownButton = uibutton(app.ResultDisplayPanel, 'push');
            app.DownButton.ButtonPushedFcn = createCallbackFcn(app, @DownButtonPushed, true);
            app.DownButton.Icon = fullfile(pathToMLAPP, 'Icon', 'down_icon.png');
            app.DownButton.Enable = 'off';
            app.DownButton.Text = '';
            app.LeftButton = uibutton(app.ResultDisplayPanel, 'push');
            app.LeftButton.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.LeftButton.Icon = fullfile(pathToMLAPP, 'Icon', 'left_icon.png');
            app.LeftButton.Enable = 'off';
            app.LeftButton.Text = '';
            app.RightButton = uibutton(app.ResultDisplayPanel, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.RightButton.Icon = fullfile(pathToMLAPP, 'Icon', 'right_icon.png');
            app.RightButton.Enable = 'off';
            app.RightButton.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MatGrabber

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end